classdef start_exp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUI         matlab.ui.Figure
        UserPanel      matlab.ui.container.Panel
        LabelUserId    matlab.ui.control.Label
        ValueUserId    matlab.ui.control.Label
        LabelUserName  matlab.ui.control.Label
        ValueUserName  matlab.ui.control.Label
        LabelUserSex   matlab.ui.control.Label
        ValueUserSex   matlab.ui.control.Label
        NewUser        matlab.ui.control.Button
        ModifyUser     matlab.ui.control.Button
        PracticePanel  matlab.ui.container.Panel
        Practice0back  matlab.ui.control.Button
        PC0back        matlab.ui.control.Label
        Practice1back  matlab.ui.control.Button
        PC1back        matlab.ui.control.Label
        Practice2back  matlab.ui.control.Button
        PC2back        matlab.ui.control.Label
        PracticeAll    matlab.ui.control.Button
        PCAll          matlab.ui.control.Label
        TestingPanel   matlab.ui.container.Panel
        TestingRun1    matlab.ui.control.Button
        TestingRun2    matlab.ui.control.Button
    end

    
    properties (Access = private)
        RegisterUserApp % user register app
        UserRegisterTime % time of registering
        UserIsRegistered = false; % indicate user is registered
        UserPracticedTimes = 0; % how many times user is practiced
        UserIsTestedRun1 = false; % test run 1 is completed
        UserIsTestedRun2 = false; % test run 2 is completed
        LogFileName; % name of file to log result data
    end
    
    properties (Access = private, Constant)
        % experiment-related properties
        ExperimentName = 'NBack';
        StimuliSet = [1:4, 6:9];
        NumberTrialsPerBlock = 10;
        % timing information
        TimeWaitStartSecs = 6;
        TimeWaitEndSecs = 5;
        TimeTaskCueSecs = 4;
        TimeFixationSecs = 0.5;
        TimeStimuliSecs = 1;
        TimeBlankSecs = 1; % this blank screen is waiting for user's response
        TimeFeedbackSecs = 0.3;
        % external files
        ImageFilePath = 'image'; % path storing instruction images
        LogFilePath = 'logs'; % path of file to log result data
    end
    
    methods (Access = private)
        % prepare for new user creation
        function initializeUserCreation(app)
            % deregister current user
            app.UserIsRegistered = false;
            app.UserPracticedTimes = 0;
            app.UserIsTestedRun1 = false;
            app.UserIsTestedRun2 = false;
            app.ValueUserId.Text = '待录入';
            app.ValueUserName.Text = '待录入';
            app.ValueUserSex.Text = '待录入';
            app.LogFileName = [];
            % disable all the buttons
            app.ModifyUser.Visible = 'off';
            app.NewUser.Enable = 'off';
            app.PracticeAll.BackgroundColor = 'white';
            app.PracticeAll.Enable = 'off';
            app.TestingRun1.BackgroundColor = 'white';
            app.TestingRun1.Enable = 'off';
            app.TestingRun2.BackgroundColor = 'white';
            app.TestingRun2.Enable = 'off';
        end
        % process practice part
        function practice(app, task)
            [status, exception, recordings] = ...
                app.start_nback('Part', "prac", 'Task', task);
            calling_button = 'PracticeAll';
            accuracy_label = 'PCAll';
            if ~isempty(task)
                switch task
                    case "zero-back"
                        calling_button = 'Practice0back';
                        accuracy_label = 'PC0back';
                    case "one-back"
                        calling_button = 'Practice1back';
                        accuracy_label = 'PC1back';
                    case "two-back"
                        calling_button = 'Practice2back';
                        accuracy_label = 'PC2back';
                end
            end
            if status ~= 0
                app.(calling_button).BackgroundColor = 'red';
                rethrow(exception)
            else
                app.(calling_button).BackgroundColor = 'green';
                app.UserPracticedTimes = app.UserPracticedTimes + 1;
                % set accuracy label
                pc = sum(recordings.acc == 1) / sum(~isnan(recordings.acc));
                app.(accuracy_label).Text = sprintf('正确率：%.1f%%', pc * 100);
                app.(accuracy_label).Visible = 'on';
            end
        end
        % process testing part
        function testing(app, run)
            app.ModifyUser.Visible = 'off';
            app.Practice0back.Enable = 'off';
            app.Practice1back.Enable = 'off';
            app.Practice2back.Enable = 'off';
            app.PracticeAll.Enable = 'off';
            [status, exception] = ...
                app.start_nback('Part', "test", 'Run', run);
            if status ~= 0
                app.(sprintf('TestingRun%d', run)).BackgroundColor = 'red';
                rethrow(exception)
            else
                app.(sprintf('UserIsTestedRun%d', run)) = true;
                app.(sprintf('TestingRun%d', run)).BackgroundColor = 'green';
            end
        end
        % initialize configurations
        config = init_config(app, part)
        % startup nback test
        [status, exception, recordings] = start_nback(app, varargin)
    end
    
    methods (Access = public)
        % register current user
        function registered = registerUser(app)
            % specify log file name template
            logfile_store_pattern = sprintf('%s-Sub_%%03d-Time_%%s.mat', app.ExperimentName);
            logfile_search_pattern = sprintf('%s-Sub_%%03d-Time_*.mat', app.ExperimentName);
            % set default as successful register
            registered = true;
            % check user name input
            if isempty(app.RegisterUserApp.Name)
                confirm_resp = uiconfirm(app.RegisterUserApp.UserRegUI, ...
                    '用户姓名好像未填写，是否返回填写？', '录入确认',  ...
                    'Options', {'是', '否'}, 'DefaultOption', '是');
                if strcmp(confirm_resp, '是')
                    registered = false;
                    return
                end
            end
            % check user identifier duplication
            search_result = dir(fullfile(app.LogFilePath, ...
                sprintf(logfile_search_pattern, app.RegisterUserApp.Identifier)));
            if ~isempty(search_result)
                confirm_resp = uiconfirm(app.RegisterUserApp.UserRegUI, ...
                    '用户编号重复，是否返回修改？', '录入确认',  ...
                    'Options', {'是', '否'}, 'DefaultOption', '是', ...
                    'Icon', 'warning');
                if strcmp(confirm_resp, '是')
                    registered = false;
                    return
                end
            end
            % store register timestamp and status
            app.UserRegisterTime = datetime;
            app.UserIsRegistered = true;
            % display user information on the main app
            app.ValueUserId.Text = num2str(app.RegisterUserApp.Identifier);
            app.ValueUserName.Text = app.RegisterUserApp.Name;
            app.ValueUserSex.Text = app.RegisterUserApp.Sex;
            % return to using matlab .mat file to store results
            app.LogFileName = sprintf(logfile_store_pattern, ...
                app.RegisterUserApp.Identifier, ...
                datestr(app.UserRegisterTime, 'yyyymmdd_HHMMSS'));
            % create user structure to store
            user.id = app.RegisterUserApp.Identifier;
            user.name = app.RegisterUserApp.Name;
            user.sex = app.RegisterUserApp.Sex;
            user.create_time = app.UserRegisterTime;
            save(fullfile(app.LogFilePath, app.LogFileName), 'user')
            % enable creating new user and modifying current user
            app.NewUser.Enable = 'on';
            app.ModifyUser.Visible = 'on';
            % enable testing now
            app.Practice0back.Enable = 'on';
            app.Practice1back.Enable = 'on';
            app.Practice2back.Enable = 'on';
            app.PracticeAll.Enable = 'on';
            app.TestingRun1.Enable = 'on';
            app.TestingRun2.Enable = 'on';
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize buttons
            app.ModifyUser.Visible = 'off';
            app.NewUser.Enable = 'on';
            app.Practice0back.Enable = 'off';
            app.PC0back.Visible = 'off';
            app.Practice1back.Enable = 'off';
            app.PC1back.Visible = 'off';
            app.Practice2back.Enable = 'off';
            app.PC2back.Visible = 'off';
            app.PracticeAll.Enable = 'off';
            app.PCAll.Visible = 'off';
            app.TestingRun1.Enable = 'off';
            app.TestingRun2.Enable = 'off';
            % create log file path if not existed
            if ~exist(app.LogFilePath, 'dir')
                mkdir(app.LogFilePath)
            end
        end

        % Button pushed function: NewUser
        function NewUserButtonPushed(app, event)
            if app.UserIsRegistered && ~(app.UserIsTestedRun1 && app.UserIsTestedRun2)
                confirm_resp = uiconfirm(app.MainUI, ...
                    '当前用户还未完成测验，是否强制新建用户？', '新建用户确认',  ...
                    'Options', {'是', '否'}, 'DefaultOption', '否');
                if strcmp(confirm_resp, '否')
                    return
                end
            end
            app.initializeUserCreation();
            app.RegisterUserApp = register_user(app);
        end

        % Button pushed function: ModifyUser
        function ModifyUserButtonPushed(app, event)
            delete(fullfile(app.LogFilePath, app.LogFileName));
            user.Identifier = str2double(app.ValueUserId.Text);
            user.Name = app.ValueUserName.Text;
            user.Sex = app.ValueUserSex.Text;
            app.initializeUserCreation();
            app.RegisterUserApp = register_user(app, user);
        end

        % Button pushed function: PracticeAll
        function PracticeAllButtonPushed(app, event)
            app.ModifyUser.Visible = 'off';
            app.practice("all");
        end

        % Button pushed function: TestingRun1
        function TestingRun1ButtonPushed(app, event)
            if app.UserIsTestedRun1
                confirm_resp = uiconfirm(app.MainUI, ...
                    '当前用户已完成第一次测验，是否需要重新开始？', '重测确认',  ...
                    'Options', {'是', '否'}, 'DefaultOption', '否');
                if strcmp(confirm_resp, '否')
                    return
                end
            end
            app.testing(1)
        end

        % Button pushed function: TestingRun2
        function TestingRun2ButtonPushed(app, event)
            if app.UserIsTestedRun2
                confirm_resp = uiconfirm(app.MainUI, ...
                    '当前用户已完成第二次测验，是否需要重新开始？', '重测确认',  ...
                    'Options', {'是', '否'}, 'DefaultOption', '否');
                if strcmp(confirm_resp, '否')
                    return
                end
            end
            app.testing(2)
        end

        % Close request function: MainUI
        function MainUICloseRequest(app, event)
            if app.UserIsRegistered && ~(app.UserIsTestedRun1 && app.UserIsTestedRun2)
                confirm_resp = uiconfirm(app.MainUI, ...
                    '当前用户还未完成测验，是否确认退出？', '退出确认',  ...
                    'Options', {'是', '否'}, 'DefaultOption', '否');
                if strcmp(confirm_resp, '否')
                    return
                end
            end
            delete(app.RegisterUserApp)
            delete(app)
        end

        % Button pushed function: Practice0back
        function Practice0backButtonPushed(app, event)
            app.ModifyUser.Visible = 'off';
            app.practice("zero-back")
        end

        % Button pushed function: Practice1back
        function Practice1backButtonPushed(app, event)
            app.ModifyUser.Visible = 'off';
            app.practice("one-back")
        end

        % Button pushed function: Practice2back
        function Practice2backButtonPushed(app, event)
            app.ModifyUser.Visible = 'off';
            app.practice("two-back")
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUI = uifigure;
            app.MainUI.Name = '测验向导';
            app.MainUI.CloseRequestFcn = createCallbackFcn(app, @MainUICloseRequest, true);
            app.UserPanel = uipanel(app.MainUI);
            app.UserPanel.Title = '当前被试';
            app.LabelUserId = uilabel(app.UserPanel);
            app.LabelUserId.Tooltip = {'输入被试的编号，必须是一个大于0的整数。'};
            app.LabelUserId.Text = '编号';
            app.ValueUserId = uilabel(app.UserPanel);
            app.ValueUserId.Text = '待录入';
            app.LabelUserName = uilabel(app.UserPanel);
            app.LabelUserName.Tooltip = {'输入被试的姓名，中英文都可以。'};
            app.LabelUserName.Text = '姓名';
            app.ValueUserName = uilabel(app.UserPanel);
            app.ValueUserName.Text = '待录入';
            app.LabelUserSex = uilabel(app.UserPanel);
            app.LabelUserSex.Tooltip = {'下拉选择被试的性别。'};
            app.LabelUserSex.Text = '性别';
            app.ValueUserSex = uilabel(app.UserPanel);
            app.ValueUserSex.Text = '待录入';
            app.NewUser = uibutton(app.UserPanel, 'push');
            app.NewUser.ButtonPushedFcn = createCallbackFcn(app, @NewUserButtonPushed, true);
            app.NewUser.Text = '新用户';
            app.ModifyUser = uibutton(app.UserPanel, 'push');
            app.ModifyUser.ButtonPushedFcn = createCallbackFcn(app, @ModifyUserButtonPushed, true);
            app.ModifyUser.Text = '修改';
            app.PracticePanel = uipanel(app.MainUI);
            app.PracticePanel.Title = '练习部分';
            app.Practice0back = uibutton(app.PracticePanel, 'push');
            app.Practice0back.ButtonPushedFcn = createCallbackFcn(app, @Practice0backButtonPushed, true);
            app.Practice0back.Text = '0-back';
            app.PC0back = uilabel(app.PracticePanel);
            app.PC0back.Text = '正确率';
            app.Practice1back = uibutton(app.PracticePanel, 'push');
            app.Practice1back.ButtonPushedFcn = createCallbackFcn(app, @Practice1backButtonPushed, true);
            app.Practice1back.Text = '1-back';
            app.PC1back = uilabel(app.PracticePanel);
            app.PC1back.Text = '正确率';
            app.Practice2back = uibutton(app.PracticePanel, 'push');
            app.Practice2back.ButtonPushedFcn = createCallbackFcn(app, @Practice2backButtonPushed, true);
            app.Practice2back.Text = '2-back';
            app.PC2back = uilabel(app.PracticePanel);
            app.PC2back.Text = '正确率';
            app.PracticeAll = uibutton(app.PracticePanel, 'push');
            app.PracticeAll.ButtonPushedFcn = createCallbackFcn(app, @PracticeAllButtonPushed, true);
            app.PracticeAll.Tooltip = {'将0-back，1-back和2-back综合在一起练习。'};
            app.PracticeAll.Text = '综合';
            app.PCAll = uilabel(app.PracticePanel);
            app.PCAll.Text = '正确率';
            app.TestingPanel = uipanel(app.MainUI);
            app.TestingPanel.Title = '正式测试';
            app.TestingRun1 = uibutton(app.TestingPanel, 'push');
            app.TestingRun1.ButtonPushedFcn = createCallbackFcn(app, @TestingRun1ButtonPushed, true);
            app.TestingRun1.Text = '第一次';
            app.TestingRun2 = uibutton(app.TestingPanel, 'push');
            app.TestingRun2.ButtonPushedFcn = createCallbackFcn(app, @TestingRun2ButtonPushed, true);
            app.TestingRun2.Text = '第二次';
        end
    end

    methods (Access = public)

        % Construct app
        function app = start_exp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUI)
        end
    end
end